classdef SCC_optimization_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        InputparametersPanel            matlab.ui.container.Panel
        NMOSthresholdvoltageVEditFieldLabel  matlab.ui.control.Label
        NMOSthresholdvoltageVEditField  matlab.ui.control.NumericEditField
        PMOSthresholdvoltageVEditFieldLabel  matlab.ui.control.Label
        PMOSthresholdvoltageVEditField  matlab.ui.control.NumericEditField
        MobilityratioEditFieldLabel     matlab.ui.control.Label
        MobilityratioEditField          matlab.ui.control.NumericEditField
        WtotalumEditFieldLabel          matlab.ui.control.Label
        WtotalumEditField               matlab.ui.control.NumericEditField
        W1Label                         matlab.ui.control.Label
        W2Label                         matlab.ui.control.Label
        W3Label                         matlab.ui.control.Label
        W4Label                         matlab.ui.control.Label
        W5Label                         matlab.ui.control.Label
        W6Label                         matlab.ui.control.Label
        W7Label                         matlab.ui.control.Label
        OptimizeButton                  matlab.ui.control.Button
        d1Label                         matlab.ui.control.Label
        d2Label                         matlab.ui.control.Label
        VG1VEditField                   matlab.ui.control.NumericEditField
        VG2VEditField                   matlab.ui.control.NumericEditField
        VG3VEditField                   matlab.ui.control.NumericEditField
        VG4VEditField                   matlab.ui.control.NumericEditField
        VG5VEditField                   matlab.ui.control.NumericEditField
        VG7VEditField                   matlab.ui.control.NumericEditField
        VINVEditField                   matlab.ui.control.NumericEditField
        VOUTVEditField                  matlab.ui.control.NumericEditField
        VG6VEditField                   matlab.ui.control.NumericEditField
        VLabel                          matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        VLabel_5                        matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        VLabel_7                        matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            app.StatusTextArea.Value="Optimization is in progress. Please wait.";
            
            VIN = app.VINVEditField.Value;
            VOUT = app.VOUTVEditField.Value;
            VT_N = app.NMOSthresholdvoltageVEditField.Value;
            VT_P = app.PMOSthresholdvoltageVEditField.Value;
            VG1 = app.VG1VEditField.Value;
            VG2 = app.VG2VEditField.Value;
            VG3 = app.VG3VEditField.Value;
            VG4 = app.VG4VEditField.Value;
            VG5 = app.VG5VEditField.Value;
            VG6 = app.VG6VEditField.Value;
            VG7 = app.VG7VEditField.Value;
            mobility = app.MobilityratioEditField.Value;
            W_total = app.WtotalumEditField.Value;
            
            VGS1 = VG1 - VIN;
            VGS2 = VG2 - 2/3*VIN;
            VGS3 = VG3 - 1/3*VIN;
            VGS4 = VG4 - 1/3*VIN;
            VGS5 = VG5 - 1/3*VIN;
            VGS6 = VG6;
            VGS7 = VG7;
            
            VGS_ideal_N = max([VGS2 VGS3 VGS5 VGS6 VGS7]); %NMOS ideal gate-to-source voltage
            VGS_ideal_P = min([VGS1 VGS4]); %PMOS ideal gate-to-source voltage
            
            [R_optimized,d_optimized,status] = Req_optimization(VIN,VOUT,VT_N,VT_P,VGS_ideal_N,VGS_ideal_P,VG1,VG2,VG3,VG4,VG5,VG6,VG7,mobility);
            W_optimized = optimized_width(W_total,R_optimized,mobility);
            
            if(status == 1) %successful
                fprintf('\nOptimized widths:\nW1 = %f um\nW2 = %f um\nW3 = %f um\nW4 = %f um\nW5 = %f um\nW6 = %f um\nW7 = %f um\n',W_optimized(1),W_optimized(2),W_optimized(3),W_optimized(4),W_optimized(5),W_optimized(6),W_optimized(7));
                fprintf('\nOptimized duty cycles:\nd1 = %f\nd2 = %f\n',d_optimized(1),d_optimized(2));
                
                app.W1Label.Text="= " + num2str(round(W_optimized(1),0)) + " um";
                app.W2Label.Text="= " + num2str(round(W_optimized(2),0)) + " um";
                app.W3Label.Text="= " + num2str(round(W_optimized(3),0)) + " um";
                app.W4Label.Text="= " + num2str(round(W_optimized(4),0)) + " um";
                app.W5Label.Text="= " + num2str(round(W_optimized(5),0)) + " um";
                app.W6Label.Text="= " + num2str(round(W_optimized(6),0)) + " um";
                app.W7Label.Text="= " + num2str(round(W_optimized(7),0)) + " um";
                
                app.d1Label.Text="= " + num2str(round(d_optimized(1),3));
                app.d2Label.Text="= " + num2str(round(1-d_optimized(1),3));
                
                app.StatusTextArea.Value="Optimization is successful! Optimized transistor widths and duty cycles are displayed on the schematic.";
            else
                fprintf('GUI failed\n');
                app.StatusTextArea.Value="Optimization failed. Please re-check your inputs. Please enter input parameters in the box (left-bottom corner) and on the schematic. Once it is done, click 'Optimize' button.";
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.W1Label.Text="";
            app.W2Label.Text="";
            app.W3Label.Text="";
            app.W4Label.Text="";
            app.W5Label.Text="";
            app.W6Label.Text="";
            app.W7Label.Text="";
            
            app.d1Label.Text="";
            app.d2Label.Text="";
            
            app.VINVEditField.Value = 0;
            app.VOUTVEditField.Value = 0;
            app.NMOSthresholdvoltageVEditField.Value = 0;
            app.PMOSthresholdvoltageVEditField.Value = 0;
            app.VG1VEditField.Value = 0;
            app.VG2VEditField.Value = 0;
            app.VG3VEditField.Value = 0;
            app.VG4VEditField.Value = 0;
            app.VG5VEditField.Value = 0;
            app.VG6VEditField.Value = 0;
            app.VG7VEditField.Value = 0;
            app.MobilityratioEditField.Value = 0;
            app.WtotalumEditField.Value = 0;
            
            app.StatusTextArea.Value="All input parameters are cleared. Please enter new values in the box (left-bottom corner) and on the schematic. Once it is done, click 'Optimize' button.";
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'FBC_2cap_MOSFET.png';
            app.InputparametersPanel = uipanel(app.UIFigure);
            app.InputparametersPanel.Title = 'Input parameters';
            app.NMOSthresholdvoltageVEditFieldLabel = uilabel(app.InputparametersPanel);
            app.NMOSthresholdvoltageVEditFieldLabel.Text = 'NMOS threshold voltage (V)';
            app.NMOSthresholdvoltageVEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.NMOSthresholdvoltageVEditField.Value = 0.5645;
            app.PMOSthresholdvoltageVEditFieldLabel = uilabel(app.InputparametersPanel);
            app.PMOSthresholdvoltageVEditFieldLabel.Text = 'PMOS threshold voltage (V)';
            app.PMOSthresholdvoltageVEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.PMOSthresholdvoltageVEditField.Value = -0.5424;
            app.MobilityratioEditFieldLabel = uilabel(app.InputparametersPanel);
            app.MobilityratioEditFieldLabel.Text = 'Mobility ratio';
            app.MobilityratioEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.MobilityratioEditField.Value = 2;
            app.WtotalumEditFieldLabel = uilabel(app.InputparametersPanel);
            app.WtotalumEditFieldLabel.Text = 'Wtotal (um)';
            app.WtotalumEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.WtotalumEditField.ValueDisplayFormat = '%.0f';
            app.WtotalumEditField.Value = 10500;
            app.W1Label = uilabel(app.UIFigure);
            app.W1Label.Text = '';
            app.W2Label = uilabel(app.UIFigure);
            app.W2Label.Text = '';
            app.W3Label = uilabel(app.UIFigure);
            app.W3Label.Text = '';
            app.W4Label = uilabel(app.UIFigure);
            app.W4Label.Text = '';
            app.W5Label = uilabel(app.UIFigure);
            app.W5Label.Text = '';
            app.W6Label = uilabel(app.UIFigure);
            app.W6Label.Text = '';
            app.W7Label = uilabel(app.UIFigure);
            app.W7Label.Text = '';
            app.OptimizeButton = uibutton(app.UIFigure, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.d1Label = uilabel(app.UIFigure);
            app.d1Label.Text = '';
            app.d2Label = uilabel(app.UIFigure);
            app.d2Label.Text = '';
            app.VG1VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG2VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG2VEditField.Value = 2.4;
            app.VG3VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG3VEditField.Value = 1.8;
            app.VG4VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG5VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG5VEditField.Value = 1.2;
            app.VG7VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG7VEditField.Value = 0.9;
            app.VINVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VINVEditField.Value = 1.8;
            app.VOUTVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VOUTVEditField.Value = 0.5;
            app.VG6VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VG6VEditField.Value = 0.9;
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.UIFigure);
            app.VLabel_5.Text = 'V';
            app.VLabel_6 = uilabel(app.UIFigure);
            app.VLabel_6.Text = 'V';
            app.VLabel_7 = uilabel(app.UIFigure);
            app.VLabel_7.Text = 'V';
            app.VLabel_8 = uilabel(app.UIFigure);
            app.VLabel_8.Text = 'V';
            app.VLabel_9 = uilabel(app.UIFigure);
            app.VLabel_9.Text = 'V';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Optimization program for Fibonacci SCC with 2 switched capacitors (TR = 1/3)';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'Welcome to the optimization program!'; ''; 'Please enter input parameters in the box (left-bottom corner) and on the schematic.'; ''; 'Once it is done, click "Optimize" button.'; ''; '"Clear" button initializes all input parameters to zero.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCC_optimization_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end